classdef Anime_Cortex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        thSlider         matlab.ui.control.Slider
        axAnime          matlab.ui.control.UIAxes
        axThData         matlab.ui.control.UIAxes
        axOtherData      matlab.ui.control.UIAxes
        otherSlider      matlab.ui.control.Slider
        PlayButton       matlab.ui.control.StateButton
        axMData          matlab.ui.control.UIAxes
        MSlider          matlab.ui.control.Slider
        tEditFieldLabel  matlab.ui.control.Label
        tEditField       matlab.ui.control.NumericEditField
        timehField       matlab.ui.control.Label
    end

    
    properties (Access = public)
        BarR_pAnimes        matlab.graphics.chart.primitive.Scatter
        BarL_pAnimes        matlab.graphics.chart.primitive.Scatter
        BodyR_pAnimes        matlab.graphics.chart.primitive.Scatter
        BodyL_pAnimes        matlab.graphics.chart.primitive.Scatter
        pThDatas       matlab.graphics.chart.primitive.Scatter
        pMDatas        matlab.graphics.chart.primitive.Scatter
        pOtherDatas    matlab.graphics.chart.primitive.Scatter
        BarR_lines          matlab.graphics.primitive.Line
        BarL_lines          matlab.graphics.primitive.Line
        BodyR_lines          matlab.graphics.primitive.Line
        BodyL_lines          matlab.graphics.primitive.Line
        time           double
        BarR_x         double
        BarR_y         double
        BarL_x         double
        BarL_y         double
        BodyR_x         double
        BodyR_y         double
        BodyL_x         double
        BodyL_y         double
        thDataArray    double
        MDataArray     double
        otherDataArray double
        pauseTime      double
        sliders        matlab.ui.control.Slider
    end
    
    
    methods (Access = private)
        
        function setPsandLines(app,targetIndex)
            for i = 1:length(app.BarR_x(1, :))
                app.BarR_pAnimes(i).XData = app.BarR_x(targetIndex, i);
                app.BarR_pAnimes(i).YData = app.BarR_y(targetIndex, i);
            end
            
            for i = 1:length(app.BarR_x(1, :)) - 1 %2つのデータを使い、かつ重心（最後）には線を引かない
                app.BarR_lines(i).XData = ...
                    [app.BarR_x(targetIndex, i), app.BarR_x(targetIndex, i+1)];
                app.BarR_lines(i).YData =...
                    [app.BarR_y(targetIndex, i), app.BarR_y(targetIndex, i+1)];
            end
            
            for i = 1:length(app.BarL_x(1, :))
                app.BarL_pAnimes(i).XData = app.BarL_x(targetIndex, i);
                app.BarL_pAnimes(i).YData = app.BarL_y(targetIndex, i);
            end
            
            for i = 1:length(app.BarL_x(1, :)) - 1 %2つのデータを使い、かつ重心（最後）には線を引かない
                app.BarL_lines(i).XData = ...
                    [app.BarL_x(targetIndex, i), app.BarL_x(targetIndex, i+1)];
                app.BarL_lines(i).YData =...
                    [app.BarL_y(targetIndex, i), app.BarL_y(targetIndex, i+1)];
            end
            
            for i = 1:length(app.BodyR_x(1, :))
                app.BodyR_pAnimes(i).XData = app.BodyR_x(targetIndex, i);
                app.BodyR_pAnimes(i).YData = app.BodyR_y(targetIndex, i);
            end
            
            for i = 1:length(app.BodyR_x(1, :)) - 1 %2つのデータを使い、かつ重心（最後）には線を引かない
                app.BodyR_lines(i).XData = ...
                    [app.BodyR_x(targetIndex, i), app.BodyR_x(targetIndex, i+1)];
                app.BodyR_lines(i).YData =...
                    [app.BodyR_y(targetIndex, i), app.BodyR_y(targetIndex, i+1)];
            end
            
            for i = 1:length(app.BodyL_x(1, :))
                app.BodyL_pAnimes(i).XData = app.BodyL_x(targetIndex, i);
                app.BodyL_pAnimes(i).YData = app.BodyL_y(targetIndex, i);
            end
            
            for i = 1:length(app.BodyL_x(1, :)) - 1 %2つのデータを使い、かつ重心（最後）には線を引かない
                app.BodyL_lines(i).XData = ...
                    [app.BodyL_x(targetIndex, i), app.BodyL_x(targetIndex, i+1)];
                app.BodyL_lines(i).YData =...
                    [app.BodyL_y(targetIndex, i), app.BodyL_y(targetIndex, i+1)];
            end
            
            for i = 1:length(app.thDataArray(1, :))
                app.pThDatas(i).XData = app.time(targetIndex);
                app.pThDatas(i).YData = app.thDataArray(targetIndex, i);
            end
            
            for i = 1:length(app.MDataArray(1, :))
                app.pMDatas(i).XData = app.time(targetIndex);
                app.pMDatas(i).YData = app.MDataArray(targetIndex, i);
            end
            
            for i = 1:length(app.otherDataArray(1, :))
                app.pOtherDatas(i).XData = app.time(targetIndex);
                app.pOtherDatas(i).YData = app.otherDataArray(targetIndex, i);
            end
            
        end
        
        function setSliders(app, value)
            for i = app.sliders
                i.Value = value;
            end
        end


    end
    
    methods (Access = public)
        function restartupFcn(app, time, BarRs, BarLs, Bodies, thDataArray, MDataArray, otherDataArray, sliderTimeh)
            startupFcn(app, time, BarRs, BarLs, Bodies, thDataArray, MDataArray, otherDataArray, sliderTimeh)
            setSliders(app, 1)
        end
        
        function FirstPlay(app)
            app.PlayButton.Value = true;
            startTime = floor(app.thSlider.Value);
            if startTime ~= app.thSlider.Limits(2)
                for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            else
                for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                        length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            end
            app.PlayButton.Value = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, time, BarRs, BarLs, BodyR, BodyL, thDataArray, MDataArray, otherDataArray, sliderTimeh)
%             if length(BarR_x)~=length(BarR_y)
%                 throw(MException('MyComponet:SizeMissmatching', 'x座標とy座標の数が合いません'))
%             end
            
            %----------------------------------------------------
            %-----------------データの入力------------------------
            %----------------------------------------------------
            app.time = time;
            
            app.BarR_x = BarRs(:,:,1);
            app.BarR_y = BarRs(:,:,2);
            app.BarL_x = BarLs(:,:,1);
            app.BarL_y = BarLs(:,:,2);
            app.BodyR_x = BodyR(:,:,1);
            app.BodyR_y = BodyR(:,:,2);
            app.BodyL_x = BodyL(:,:,1);
            app.BodyL_y = BodyL(:,:,2);
            
            app.thDataArray = thDataArray;
            app.MDataArray = MDataArray;
            app.otherDataArray = otherDataArray;
            app.sliders = [app.thSlider, app.MSlider, app.otherSlider];
            app.tEditField.Value = app.time(1);
            
            app.pauseTime = 0.05;
            
            %             app.pauseTime = (app.time(2) - app.time(1));
            %             if(app.pauseTime < 0.1)
            %                 app.pauseTime = 0.1;
            %             end
            
%             app.BarR_x = [zeros(length(app.BarR_x(:, 1)), 1), app.BarR_x];
%             app.BarR_y = [zeros(length(app.BarR_y(:, 1)), 1), app.BarR_y];
            
            %----------------------------------------------------
            %-----------------刻み幅の表示------------------------
            %----------------------------------------------------
            app.timehField.Text = strcat('h = ', num2str(app.time(2)-app.time(1),'%10.3e'));
            
            
            %----------------------------------------------------
            %-----------------BarRの表示--------------------------
            %----------------------------------------------------
%             app.pAnimes(1) = scatter(app.axAnime, 0, 0);
            hold(app.axAnime, 'on')
            for i = 1:length(app.BarR_x(1, :))
%                 if i == 3
%                     app.pAnimes(i) = scatter(app.axAnime, app.BarR_x(1, i), app.BarR_y(1, i), 100, 'rp');
%                 else
                    app.BarR_pAnimes(i) = scatter(app.axAnime, app.BarR_x(1, i), app.BarR_y(1, i), 'black', 'fill');
%                 end
            end
            
            for i = 1:length(app.BarR_x(1, :)) - 1
%                 if i == 3
%                     app.BarR_lines(i-1) = line(app.axAnime,...
%                         [app.BarR_x(1, i), app.BarR_x(1, i+1)],...
%                         [app.BarR_y(1, i), app.BarR_y(1, i+1)], "linewidth", 3);
%                 else
                    app.BarR_lines(i) = line(app.axAnime,...
                        [app.BarR_x(1, i), app.BarR_x(1, i+1)],...
                        [app.BarR_y(1, i), app.BarR_y(1, i+1)], 'color', 'black', 'linewidth', 1);
%                 end
            end
            
            %----------------------------------------------------
            %-----------------BarLの表示--------------------------
            %----------------------------------------------------
            for i = 1:length(app.BarL_x(1, :))
%                 if i == 3
%                     app.pAnimes(i) = scatter(app.axAnime, app.BarL_x(1, i), app.BarL_y(1, i), 100, 'rp');
%                 else
                    app.BarL_pAnimes(i) = scatter(app.axAnime, app.BarL_x(1, i), app.BarL_y(1, i), 'black', 'fill');
%                 end
            end
            
            for i = 1:length(app.BarL_x(1, :)) - 1
%                 if i == 3
%                     app.lines(i-1) = line(app.axAnime,...
%                         [app.BarL_x(1, i), app.BarL_x(1, i+1)],...
%                         [app.BarL_y(1, i), app.BarL_y(1, i+1)], "linewidth", 3);
%                 else
                    app.BarL_lines(i) = line(app.axAnime,...
                        [app.BarL_x(1, i), app.BarL_x(1, i+1)],...
                        [app.BarL_y(1, i), app.BarL_y(1, i+1)], 'color', 'black', 'linewidth', 1);
%                 end
            end
            
            %----------------------------------------------------
            %-----------------BodyRの表示--------------------------
            %----------------------------------------------------
            for i = 1:length(app.BodyR_x(1, :))
%                 if i == 3
%                     app.pAnimes(i) = scatter(app.axAnime, app.BodyR_x(1, i), app.BodyR_y(1, i), 100, 'rp');
%                 else
                    app.BodyR_pAnimes(i) = scatter(app.axAnime, app.BodyR_x(1, i), app.BodyR_y(1, i), 'r', 'fill');
%                 end
            end
            
            for i = 1:length(app.BodyR_x(1, :)) - 1
%                 if i == 3
%                     app.lines(i-1) = line(app.axAnime,...
%                         [app.BodyR_x(1, i), app.BodyR_x(1, i+1)],...
%                         [app.BodyR_y(1, i), app.BodyR_y(1, i+1)], "linewidth", 3);
%                 else
                    app.BodyR_lines(i) = line(app.axAnime,...
                        [app.BodyR_x(1, i), app.BodyR_x(1, i+1)],...
                        [app.BodyR_y(1, i), app.BodyR_y(1, i+1)], 'color', 'red', 'linewidth', 1);
%                 end
            end
            
            %----------------------------------------------------
            %-----------------BodyLの表示--------------------------
            %----------------------------------------------------
            for i = 1:length(app.BodyL_x(1, :))
%                 if i == 3
%                     app.pAnimes(i) = scatter(app.axAnime, app.BodyR_x(1, i), app.BodyR_y(1, i), 100, 'rp');
%                 else
                    app.BodyL_pAnimes(i) = scatter(app.axAnime, app.BodyL_x(1, i), app.BodyL_y(1, i), 'blue', 'fill');
%                 end
            end
            
            for i = 1:length(app.BodyL_x(1, :)) - 1
%                 if i == 3
%                     app.lines(i-1) = line(app.axAnime,...
%                         [app.BodyR_x(1, i), app.BodyR_x(1, i+1)],...
%                         [app.BodyR_y(1, i), app.BodyR_y(1, i+1)], "linewidth", 3);
%                 else
                    app.BodyL_lines(i) = line(app.axAnime,...
                        [app.BodyL_x(1, i), app.BodyL_x(1, i+1)],...
                        [app.BodyL_y(1, i), app.BodyL_y(1, i+1)], 'color', 'blue', 'linewidth', 1, 'LineStyle', ':');
%                 end
            end
            
            
            %----------------------------------------------------
            %-----------------重心の表示--------------------------
            %----------------------------------------------------
%             i = length(app.BarR_x(1,:));
%             app.pAnimes(i) = scatter(app.axAnime, app.BarR_x(1, i), app.BarR_y(1, i), 100, 'r', 'x');
            
            hold(app.axAnime, 'off')
            
            
            %----------------------------------------------------
            %-----------------角度の表示--------------------------
            %----------------------------------------------------
            plot(app.axThData, app.time, app.thDataArray)
            app.axThData.XLim = [app.time(1), app.time(end)];
            hold(app.axThData, 'on')
            for i = 1:length(app.thDataArray(1, :))
                app.pThDatas(i) = ...
                    scatter(app.axThData, app.time(1), app.thDataArray(1, i), 100, 'r', 'x');
            end
            hold(app.axThData, 'off')
            grid(app.axThData, 'on')
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            %             sliderTimeh = 0.2;
            app.thSlider.Limits = [1, length(app.time)];
            app.thSlider.MajorTickLabels = {};
            app.thSlider.MajorTicks = [];
            %             app.thSlider.MajorTicks = find(mod(app.time, sliderTimeh)<(app.time(2) - app.time(1))*0.9);
            %             app.thSlider.MajorTickLabels = string(app.time(mod(app.time,sliderTimeh)<(app.time(2) - app.time(1))*0.9));
            %             app.thSlider.MajorTicks = find(mod(app.time, sliderTimeh)==0);
            %             app.thSlider.MajorTickLabels = string(app.time(mod(app.time,sliderTimeh)==0));
            
            
            app.thSlider.Position(1) = app.axThData.InnerPosition(1);
            app.thSlider.Position(3) = app.axThData.InnerPosition(3);
            
            %----------------------------------------------------
            %-----------------トルクデータの表示-------------------
            %----------------------------------------------------
            plot(app.axMData, app.time, app.MDataArray)
            app.axMData.XLim = [app.time(1), app.time(end)];
            hold(app.axMData, 'on')
            for i = 1:length(app.MDataArray(1, :))
                app.pMDatas(i) = ...
                    scatter(app.axMData, app.time(1), app.MDataArray(1, i), 100, 'r', 'x');
            end
            hold(app.axMData, 'off')
            grid(app.axMData, 'on')
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            app.MSlider.Limits = app.thSlider.Limits;
            app.MSlider.MajorTicks = app.thSlider.MajorTicks;
            app.MSlider.MajorTickLabels = app.thSlider.MajorTickLabels;
            
            app.MSlider.Position(1) = app.axMData.InnerPosition(1);
            app.MSlider.Position(3) = app.axMData.InnerPosition(3);
            
            %----------------------------------------------------
            %-----------------他データの表示----------------------
            %----------------------------------------------------
            plot(app.axOtherData, app.time, app.otherDataArray)
            app.axOtherData.XLim = [app.time(1), app.time(end)];
            hold(app.axOtherData, 'on')
            for i = 1:length(app.otherDataArray(1, :))
                app.pOtherDatas(i) = ...
                    scatter(app.axOtherData, app.time(1), app.otherDataArray(1,i), 100, 'r', 'x');
            end
            hold(app.axOtherData, 'off')
            grid(app.axOtherData, 'on')
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            app.otherSlider.Limits = app.thSlider.Limits;
            app.otherSlider.MajorTicks = app.thSlider.MajorTicks;
            app.otherSlider.MajorTickLabels = app.thSlider.MajorTickLabels;
            
            app.otherSlider.Position(1) = app.axOtherData.InnerPosition(1);
            app.otherSlider.Position(3) = app.axOtherData.InnerPosition(3);
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                startTime = floor(app.thSlider.Value);
                if startTime ~= app.thSlider.Limits(2)
                    for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                            
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                else
                    for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                            length(app.time)]
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                end
                app.PlayButton.Value = false;
            end
        end

        % Callback function: MSlider, MSlider, otherSlider, thSlider
        function sliderValueChanging(app, event)
            changingValue = event.Value;
            setSliders(app, floor(changingValue + 0.5));
            setPsandLines(app,floor(changingValue + 0.5));
            app.tEditField.Value = app.time(floor(changingValue + 0.5));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            targetIndex = floor(value/(app.time(2)-app.time(1)));
            setSliders(app, targetIndex)
            setPsandLines(app,targetIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.thSlider = uislider(app.UIFigure);
            app.thSlider.Limits = [0 5];
            app.thSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.axAnime = uiaxes(app.UIFigure);
            title(app.axAnime, '')
            xlabel(app.axAnime, 'x')
            ylabel(app.axAnime, 'y')
            app.axAnime.XLim = [-0.75 3.75];
            app.axAnime.YLim = [0 4.5];
            app.axThData = uiaxes(app.UIFigure);
            title(app.axThData, '')
            xlabel(app.axThData, 'time')
            ylabel(app.axThData, '')
            app.axOtherData = uiaxes(app.UIFigure);
            title(app.axOtherData, '')
            xlabel(app.axOtherData, 'time')
            ylabel(app.axOtherData, '')
            app.otherSlider = uislider(app.UIFigure);
            app.otherSlider.Limits = [0 5];
            app.otherSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.axMData = uiaxes(app.UIFigure);
            title(app.axMData, '')
            xlabel(app.axMData, 'time')
            ylabel(app.axMData, '')
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.Limits = [0 5];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.timehField = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Anime_Cortex(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end